classdef FittingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ImportDataTab                   matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        FolderFilesTab                  matlab.ui.container.Tab
        LoadAppstatewithalldataButton   matlab.ui.control.StateButton
        SaveAppStatewithalldataButton   matlab.ui.control.StateButton
        ImportselectedfilesButton       matlab.ui.control.StateButton
        FindfoldertoimportButton        matlab.ui.control.StateButton
        UITableFolderFiles              matlab.ui.control.Table
        ImportedDataTab                 matlab.ui.container.Tab
        UITableImportedData             matlab.ui.control.Table
        FittingTab                      matlab.ui.container.Tab
        FittingTabGroup                 matlab.ui.container.TabGroup
        KKFittingTab                    matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        KKFittingTabGroup               matlab.ui.container.TabGroup
        KKDataSelectionTab              matlab.ui.container.Tab
        FitKKonselecteddataButton       matlab.ui.control.StateButton
        KKLogNyquistCheckBox            matlab.ui.control.CheckBox
        KKClearplotButton               matlab.ui.control.StateButton
        KKPlotSelectedDataButton        matlab.ui.control.StateButton
        UITableImportedData_KK          matlab.ui.control.Table
        AdvancedSettingsTab_KK          matlab.ui.container.Tab
        UIKKFittingTable                matlab.ui.control.Table
        UIAxesKKPhase                   matlab.ui.control.UIAxes
        UIAxesKKMod                     matlab.ui.control.UIAxes
        UIAxesKKError                   matlab.ui.control.UIAxes
        UIAxesKKNyquist                 matlab.ui.control.UIAxes
        KKFitValuesTab                  matlab.ui.container.Tab
        UIAxesKKParameters              matlab.ui.control.UIAxes
        EquivilentCircuitFittingTab     matlab.ui.container.Tab
        FitCircuitDropDown              matlab.ui.control.DropDown
        FitCircuitDropDownLabel         matlab.ui.control.Label
        PlotKKfitsCheckBox              matlab.ui.control.CheckBox
        ComputeselectedfitsButton       matlab.ui.control.StateButton
        UITableECFits                   matlab.ui.control.Table
        ECClearplotButton               matlab.ui.control.StateButton
        ECLogNyquistCheckBox            matlab.ui.control.CheckBox
        ECPlotSelectedDataButton        matlab.ui.control.StateButton
        UIECParameterTable              matlab.ui.control.Table
        UIECFittingGoFTable             matlab.ui.control.Table
        ECPlottingTabGroup              matlab.ui.container.TabGroup
        ECDataSelectionTab              matlab.ui.container.Tab
        UseasHighImpedanceMeasurementButton  matlab.ui.control.StateButton
        UseasLowImpedanceMeasurementButton  matlab.ui.control.StateButton
        UITableImportedData_EC          matlab.ui.control.Table
        AdvancedTab                     matlab.ui.container.Tab
        OnlyfitwithinthesefrequencyboundsCheckBox  matlab.ui.control.CheckBox
        LowerFittingFrequencyEditField  matlab.ui.control.NumericEditField
        LowerFittingFrequencyEditFieldLabel  matlab.ui.control.Label
        UpperFittingFrequencyEditField  matlab.ui.control.NumericEditField
        UpperFittingFrequencyEditFieldLabel  matlab.ui.control.Label
        ShowIterationsoffittingCheckBox  matlab.ui.control.CheckBox
        UseSimplexFittoSeedothermethodsCheckBox  matlab.ui.control.CheckBox
        SimplexMultiplierEditField      matlab.ui.control.NumericEditField
        SimplexMultiplierLabel          matlab.ui.control.Label
        SubtractLowImpedanceCheckBox    matlab.ui.control.CheckBox
        SubtractHighImpedanceCheckBox   matlab.ui.control.CheckBox
        GuessupdateinterationsEditField  matlab.ui.control.NumericEditField
        GuessupdateinterationsEditFieldLabel  matlab.ui.control.Label
        ToleranceEditField              matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel         matlab.ui.control.Label
        RandomInitialGuessCheckBox      matlab.ui.control.CheckBox
        MaxFuncEvaluationsperparameterEditField  matlab.ui.control.NumericEditField
        MaxFuncEvaluationsperparameterLabel  matlab.ui.control.Label
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        UIAxesECPhase                   matlab.ui.control.UIAxes
        UIAxesECMod                     matlab.ui.control.UIAxes
        UIAxesECError                   matlab.ui.control.UIAxes
        UIAxesECNyquist                 matlab.ui.control.UIAxes
        ECFitValuesTab                  matlab.ui.container.Tab
        FittingMethodDropDown           matlab.ui.control.DropDown
        FittingMethodDropDownLabel      matlab.ui.control.Label
        UIFitParameterTable             matlab.ui.control.Table
        UITableOutputTable              matlab.ui.control.Table
        ExitMessageTextArea             matlab.ui.control.TextArea
        ExitMessageTextAreaLabel        matlab.ui.control.Label
        UIAxesParameters                matlab.ui.control.UIAxes
        CircuitSelectionTab             matlab.ui.container.Tab
        AddtolistButton                 matlab.ui.control.StateButton
        ResultEditField                 matlab.ui.control.EditField
        ResultEditFieldLabel            matlab.ui.control.Label
        SimplifiedCircuitEditField      matlab.ui.control.EditField
        SimplifiedCircuitEditFieldLabel  matlab.ui.control.Label
        CircuitValidatorEditField       matlab.ui.control.EditField
        CircuitValidatorEditFieldLabel  matlab.ui.control.Label
        CheckButton                     matlab.ui.control.StateButton
        UIECFittingTable                matlab.ui.control.Table
        AdvancedSettingsTab             matlab.ui.container.Tab
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
        Measurements = struct();
    end
    
    methods (Access = private)
        
        function ClearKKplots(app)
            % Create UIAxesNyquist
            delete(app.UIAxesKKNyquist)
            app.UIAxesKKNyquist = uiaxes(app.KKFittingTab);
            title(app.UIAxesKKNyquist, 'Nyquist')
            xlabel(app.UIAxesKKNyquist, 'Real Z')
            ylabel(app.UIAxesKKNyquist, '- Imaginary Z')
            zlabel(app.UIAxesKKNyquist, 'Z')
            app.UIAxesKKNyquist.XGrid = 'on';
            app.UIAxesKKNyquist.YGrid = 'on';
            app.UIAxesKKNyquist.Position = [13 540 395 238];
            h=plot(app.UIAxesKKNyquist,NaN,NaN);
            delete(h);

            % Create UIAxesError
            delete(app.UIAxesKKError)
            app.UIAxesKKError = uiaxes(app.KKFittingTab);
            title(app.UIAxesKKError, 'Error')
            xlabel(app.UIAxesKKError, 'Frequency')
            ylabel(app.UIAxesKKError, 'Residuals')
            zlabel(app.UIAxesKKError, 'Z')
            app.UIAxesKKError.XGrid = 'on';
            app.UIAxesKKError.YGrid = 'on';
            app.UIAxesKKError.Position = [426 540 395 238];
            h=plot(app.UIAxesKKError,NaN,NaN);
            delete(h);

            % Create UIAxesMod
            delete(app.UIAxesKKMod)
            app.UIAxesKKMod = uiaxes(app.KKFittingTab);
            title(app.UIAxesKKMod, 'Modulus')
            xlabel(app.UIAxesKKMod, 'Frequency (Hz)')
            ylabel(app.UIAxesKKMod, '| Z | (ohms)')
            zlabel(app.UIAxesKKMod, 'Z')
            app.UIAxesKKMod.XGrid = 'on';
            app.UIAxesKKMod.YGrid = 'on';
            app.UIAxesKKMod.Position = [13 285 395 238];
            h=plot(app.UIAxesKKMod,NaN,NaN);
            delete(h);

            % Create UIAxesPhase
            delete(app.UIAxesKKPhase)
            app.UIAxesKKPhase = uiaxes(app.KKFittingTab);
            title(app.UIAxesKKPhase, 'Phase')
            xlabel(app.UIAxesKKPhase, 'Frequency (Hz)')
            ylabel(app.UIAxesKKPhase, '\theta (degrees)')
            zlabel(app.UIAxesKKPhase, 'Z')
            app.UIAxesKKPhase.XGrid = 'on';
            app.UIAxesKKPhase.YGrid = 'on';
            app.UIAxesKKPhase.Position = [426 285 395 238];
            h=plot(app.UIAxesKKPhase,NaN,NaN);
            delete(h);
        end
        
        function ClearECplots(app)
            % Create UIAxesECNyquist
            delete(app.UIAxesECNyquist)
            app.UIAxesECNyquist = uiaxes(app.EquivilentCircuitFittingTab);
            title(app.UIAxesECNyquist, 'Nyquist')
            xlabel(app.UIAxesECNyquist, 'Real Z')
            ylabel(app.UIAxesECNyquist, '- Imaginary Z')
            zlabel(app.UIAxesECNyquist, 'Z')
            app.UIAxesECNyquist.Position = [7 541 395 238];
            h=plot(app.UIAxesECNyquist,NaN,NaN);
            delete(h);

            % Create UIAxesECError
            delete(app.UIAxesECError)
            app.UIAxesECError = uiaxes(app.EquivilentCircuitFittingTab);
            title(app.UIAxesECError, 'Error')
            xlabel(app.UIAxesECError, 'Frequency')
            ylabel(app.UIAxesECError, 'Residuals')
            zlabel(app.UIAxesECError, 'Z')
            app.UIAxesECError.Position = [420 541 395 238];
            h=plot(app.UIAxesECError,NaN,NaN);
            delete(h);

            % Create UIAxesECMod
            delete(app.UIAxesECMod)
            app.UIAxesECMod = uiaxes(app.EquivilentCircuitFittingTab);
            title(app.UIAxesECMod, 'Modulus')
            xlabel(app.UIAxesECMod, 'Frequency (Hz)')
            ylabel(app.UIAxesECMod, '| Z | (ohms)')
            zlabel(app.UIAxesECMod, 'Z')
            app.UIAxesECMod.Position = [7 286 395 238];
            h=plot(app.UIAxesECMod,NaN,NaN);
            delete(h);

            % Create UIAxesECPhase
            delete(app.UIAxesECPhase)
            app.UIAxesECPhase = uiaxes(app.EquivilentCircuitFittingTab);
            title(app.UIAxesECPhase, 'Phase')
            xlabel(app.UIAxesECPhase, 'Frequency (Hz)')
            ylabel(app.UIAxesECPhase, '\theta (degrees)')
            zlabel(app.UIAxesECPhase, 'Z')
            app.UIAxesECPhase.Position = [420 286 395 238];
            h=plot(app.UIAxesECPhase,NaN,NaN);
            delete(h);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % populate default circuits in circuits tables
            circuits = ["s(R,p(C,R))";"s(R,p(C,s(R,p(C,R))))";...
                "s(R,p(C,R,T))";"s(R,p(s(R,p(R,C)),C,T))";...
                "s(R,p(C,R),T)";"s(R,p(C,s(R,p(C,R))),T)"
                "s(R,p(C,R,s(O,O)))";"s(R,p(s(R,p(R,C)),C,s(O,O)))";...
                "s(R,p(C,R,p(O,O)))";"s(R,p(s(R,p(R,C)),C,p(O,O)))";...
                "s(R,p(C,R,s(O,p(O,O))))";"s(R,p(s(R,p(R,C)),C,s(O,p(O,O))))";...
                "s(R,p(C,R,s(p(O,O),p(O,O))))";"s(R,p(s(R,p(R,C)),C,s(p(O,O),p(O,O))))"];
            selection = false(length(circuits),1);
            selection(1)=true;
            for i = 1:length(circuits)
                numEl{i,1} = getNumElements(parseCircuitString(circuits(i)));
                numParams{i,1} = 0;
                circuits(i) = simplifyCircuitString(circuits(i));
            end
            t = table(selection, circuits,numParams,numEl);
            app.UIECFittingTable.Data = t;
            app.UITableECFits.Data = table(circuits);
            app.FitCircuitDropDown.Items = ["Kramers-Kronig Fit"; circuits];
        end

        % Value changed function: FindfoldertoimportButton
        function FindfoldertoimportButtonValueChanged(app, event)
            value = app.FindfoldertoimportButton.Value;
            if value
                % Select folder pop up
                try
                    importfolder=uigetdir(fileparts(matlab.desktop.editor.getActiveFilename),'Choose which folder of data to import');
                catch ME
                    warning('Unable to use current folder method:', ME);
                    importfolder=uigetdir('C:\','Choose which folder of data to import');
                end
                dtafilelist = dir([importfolder '\*.dta']);
                csvfilelist = dir([importfolder '\*.csv']);
                for i = 1:length(dtafilelist)
                    filename{i,1}= dtafilelist(i).name(1:end-4);
                    folderpath{i,1} = dtafilelist(i).folder;
                    fullpath{i,1} = fullfile(dtafilelist(i).folder,dtafilelist(i).name);
                    date{i,1}=datetime(dtafilelist(i).date);
                end
                m = length(dtafilelist);
                for i = 1:length(csvfilelist)
                    filename{m+i,1}= csvfilelist(i).name(1:end-4);
                    folderpath{m+i,1} = csvfilelist(i).folder;
                    fullpath{m+i,1} = fullfile(csvfilelist(i).folder,csvfilelist(i).name);
                    date{m+i,1}=datetime(csvfilelist(i).date);
                end
                t=table(filename,date,folderpath,fullpath);
                % populate Table
                app.UITableFolderFiles.Data = t;
            end
            app.FindfoldertoimportButton.Value = 0;
        end

        % Value changed function: ImportselectedfilesButton
        function ImportselectedfilesButtonValueChanged(app, event)
            value = app.ImportselectedfilesButton.Value;
            if value
                % get selected/ highlighted rows
                if ~isempty(app.UITableFolderFiles.Selection)
                    % import those files to app.measurements\
                    currentlength = length(app.Measurements);
                    if ~isfield(app.Measurements(currentlength),'data') && currentlength == 1
                        currentlength = 0;
                    end
                    for i = 1:length(app.UITableFolderFiles.Selection)
                        ind = app.UITableFolderFiles.Selection(i);
                        filefullpath = app.UITableFolderFiles.Data.fullpath{ind};
                        if strcmp(filefullpath(end-2:end),'DTA') || strcmp(filefullpath(end-2:end),'dta') 
                            app.Measurements(currentlength+i).data = importGamryDTAfile(filefullpath);
                        elseif strcmp(filefullpath(end-2:end),'csv')
                            app.Measurements(currentlength+i).data = importCHI_CSVfile(filefullpath);
                        else
                            disp('data file extension unknown');
                        end
                        app.Measurements(currentlength+i).filename = app.UITableFolderFiles.Data.filename{ind};
                        app.Measurements(currentlength+i).folderpath = app.UITableFolderFiles.Data.folderpath{ind};
                        app.Measurements(currentlength+i).fullpath = filefullpath;
                        app.Measurements(currentlength+i).date = app.UITableFolderFiles.Data.date{ind};
                    end
                    % construct data cells for UI Table
                    for i = 1:length(app.Measurements)
                        importedfilename{i,1} = app.Measurements(i).filename;
                        importeddate{i,1} = app.Measurements(i).date;
                        importedfolderpath{i,1} = app.Measurements(i).folderpath;
                        importedfullpath{i,1} = app.Measurements(i).fullpath;
                    end
                    % update UITables
                    t = table(importedfilename, importeddate, importedfolderpath,importedfullpath);
                    app.UITableImportedData.Data = t;
                    app.UITableImportedData_KK.Data = t;
                    app.UITableImportedData_EC.Data = t;
                else
                    disp('nothing selected to import')
                end
            end
            app.ImportselectedfilesButton.Value = 0;
        end

        % Selection changed function: UITableFolderFiles
        function UITableFolderFilesSelectionChanged(app, event)
            %app.ImportSelection = app.UITableFolderFiles.Selection;
            
        end

        % Value changed function: KKPlotSelectedDataButton
        function KKPlotSelectedDataButtonValueChanged(app, event)
            value = app.KKPlotSelectedDataButton.Value;
            if value
                % get selection
                sel=app.UITableImportedData_KK.Selection;
                if ~isempty(sel)
                    % clear plots
                    app.KKClearplotButton.Value = 1;
                    app.KKClearplotButtonValueChanged
                    % check log-spaced nyquist button
                    if app.KKLogNyquistCheckBox.Value
                        % plot nyquist
                        loglog(app.UIAxesKKNyquist,app.Measurements(sel(1)).data.Zreal, -app.Measurements(sel(1)).data.Zimag, '.','DisplayName','Data');
                        hold(app.UIAxesKKNyquist,'on')
                        % plot fits if they exist
                        if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit)
                            loglog(app.UIAxesKKNyquist,real(app.Measurements(sel(1)).KKfit.simplex.fitcurve),...
                                -imag(app.Measurements(sel(1)).KKfit.simplex.fitcurve), '-','DisplayName','Simplex');
                            loglog(app.UIAxesKKNyquist,real(app.Measurements(sel(1)).KKfit.levenbergMarquardt.fitcurve),...
                                -imag(app.Measurements(sel(1)).KKfit.levenbergMarquardt.fitcurve), '--','DisplayName','Levenberg-Marquardt');
                            loglog(app.UIAxesKKNyquist,real(app.Measurements(sel(1)).KKfit.trustRegion.fitcurve),...
                                -imag(app.Measurements(sel(1)).KKfit.trustRegion.fitcurve), '-.k','DisplayName','Trust-Region-Reflexive');
                        end
                    else
                        % plot nyquist
                        plot(app.UIAxesKKNyquist,app.Measurements(sel(1)).data.Zreal, -app.Measurements(sel(1)).data.Zimag, '.','DisplayName','Data');
                        hold(app.UIAxesKKNyquist,'on')
                        % plot fits if they exist
                        if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit)
                            plot(app.UIAxesKKNyquist,real(app.Measurements(sel(1)).KKfit.simplex.fitcurve),...
                                -imag(app.Measurements(sel(1)).KKfit.simplex.fitcurve), '-','DisplayName','Simplex');
                            plot(app.UIAxesKKNyquist,real(app.Measurements(sel(1)).KKfit.levenbergMarquardt.fitcurve),...
                                -imag(app.Measurements(sel(1)).KKfit.levenbergMarquardt.fitcurve), '--','DisplayName','Levenberg-Marquardt');
                            plot(app.UIAxesKKNyquist,real(app.Measurements(sel(1)).KKfit.trustRegion.fitcurve),...
                                -imag(app.Measurements(sel(1)).KKfit.trustRegion.fitcurve), '-.k','DisplayName','Trust-Region-Reflexive');
                        end
                    end
                    hold(app.UIAxesKKNyquist,'off')
                    grid(app.UIAxesKKNyquist,'on')
                    xlabel(app.UIAxesKKNyquist,'Real(Z)');
                    ylabel(app.UIAxesKKNyquist,'-Imag(Z)');
                    legend(app.UIAxesKKNyquist,'Location','south');
                    
                    % plot Zmod
                    loglog(app.UIAxesKKMod,app.Measurements(sel(1)).data.Freq, app.Measurements(sel(1)).data.Zmod, '.','DisplayName','Data');
                    hold(app.UIAxesKKMod,'on')
                    % plot fits if they exist
                    if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit)
                        loglog(app.UIAxesKKMod,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).KKfit.simplex.fitcurve), '-','DisplayName','Simplex');
                        loglog(app.UIAxesKKMod,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).KKfit.levenbergMarquardt.fitcurve), '--','DisplayName','Levenberg-Marquardt');
                        loglog(app.UIAxesKKMod,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).KKfit.trustRegion.fitcurve), '-.k','DisplayName','Trust-Region-Reflexive');
                    end
                    hold(app.UIAxesKKMod,'off')
                    grid(app.UIAxesKKMod,'on')
                    xlim(app.UIAxesKKMod,[app.Measurements(sel(1)).data.Freq(end) app.Measurements(sel(1)).data.Freq(1)]);
                    xlabel(app.UIAxesKKMod,'Frequency (Hz)');
                    ylabel(app.UIAxesKKMod,'Impedance Magnitude |Z| (ohms)');
                    legend(app.UIAxesKKMod,'Location','northeast');

                    % plot Z phase
                    semilogx(app.UIAxesKKPhase,app.Measurements(sel(1)).data.Freq, app.Measurements(sel(1)).data.Zphz, '.','DisplayName','Data');
                    hold(app.UIAxesKKPhase,'on')
                    if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit)
                        semilogx(app.UIAxesKKPhase,app.Measurements(sel(1)).data.Freq, 180/pi*angle(app.Measurements(sel(1)).KKfit.simplex.fitcurve), '-','DisplayName','Simplex');
                        semilogx(app.UIAxesKKPhase,app.Measurements(sel(1)).data.Freq, 180/pi*angle(app.Measurements(sel(1)).KKfit.levenbergMarquardt.fitcurve), '--','DisplayName','Levenberg-Marquardt');
                        semilogx(app.UIAxesKKPhase,app.Measurements(sel(1)).data.Freq, 180/pi*angle(app.Measurements(sel(1)).KKfit.trustRegion.fitcurve), '-.k','DisplayName','Trust-Region-Reflexive');
                    end
                    hold(app.UIAxesKKPhase,'off')
                    grid(app.UIAxesKKPhase,'on')
                    xlim(app.UIAxesKKPhase,[app.Measurements(sel(1)).data.Freq(end) app.Measurements(sel(1)).data.Freq(1)]);
                    xlabel(app.UIAxesKKPhase,'Frequency (Hz)');
                    ylabel(app.UIAxesKKPhase,'Phase (degrees)');
                    legend(app.UIAxesKKPhase,'Location','north');

                    % plot error
                    if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit)
                        loglog(app.UIAxesKKError,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).KKfit.simplex.residuals), '-','DisplayName','Simplex');
                        hold(app.UIAxesKKError,'on')
                        loglog(app.UIAxesKKError,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).KKfit.levenbergMarquardt.residuals), '--','DisplayName','Levenberg-Marquardt');
                        loglog(app.UIAxesKKError,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).KKfit.trustRegion.residuals), '-.k','DisplayName','Trust-Region-Reflexive');
                        hold(app.UIAxesKKError,'off')
                        grid(app.UIAxesKKError,'on')
                        xlim(app.UIAxesKKError,[app.Measurements(sel(1)).data.Freq(end) app.Measurements(sel(1)).data.Freq(1)]);
                        xlabel(app.UIAxesKKError,'Frequency (Hz)');
                        ylabel(app.UIAxesKKError,'Weighted Residuals');
                        legend(app.UIAxesKKError,'Location','northeast');
                    end
                    % plot KK fit values
                    if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit)
                        l=length(app.Measurements(sel(1)).KKfit.v0);
                        scatter(app.UIAxesKKParameters,1:l, app.Measurements(sel(1)).KKfit.simplex.coeff, '.','DisplayName','Simplex');
                        hold(app.UIAxesKKParameters,'on')
                        scatter(app.UIAxesKKParameters,1:l, app.Measurements(sel(1)).KKfit.levenbergMarquardt.coeff, 'o','MarkerEdgeColor','r','DisplayName','Levenberg-Marquardt');
                        scatter(app.UIAxesKKParameters,1:l, app.Measurements(sel(1)).KKfit.trustRegion.coeff, 'd','MarkerEdgeColor','g','DisplayName','Trust-Region-Reflexive');
                        scatter(app.UIAxesKKParameters,1:l, app.Measurements(sel(1)).KKfit.v0, 'x','MarkerEdgeColor','k','DisplayName','Initial Guess')
                        hold(app.UIAxesKKParameters,'off')
                        grid(app.UIAxesKKParameters,'on')
                        set(app.UIAxesKKParameters, 'YScale', 'log');
                        
                        xlim(app.UIAxesKKParameters,[1 l]);
                        %xlabel(app.UIAxesKKError,'Frequency (Hz)');
                        %ylabel(app.UIAxesKKError,'Weighted Residuals');
                        legend(app.UIAxesKKParameters,'Location','northeast');
                    end
                    % update gof table
                    if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit)
                        t = {};
                        t{1,1} = app.Measurements(sel(1)).KKfit.simplex.gof;
                        t{1,2} = app.Measurements(sel(1)).KKfit.simplex.R2;
                        t{1,3} = app.Measurements(sel(1)).KKfit.simplex.R2adjusted;
                        t{1,4} = 'Simplex';
                        t{2,1} = app.Measurements(sel(1)).KKfit.levenbergMarquardt.gof;
                        t{2,2} = app.Measurements(sel(1)).KKfit.levenbergMarquardt.R2;
                        t{2,3} = app.Measurements(sel(1)).KKfit.levenbergMarquardt.R2adjusted;
                        t{2,4} = 'Levenberg-Marquardt';
                        t{3,1} = app.Measurements(sel(1)).KKfit.trustRegion.gof;
                        t{3,2} = app.Measurements(sel(1)).KKfit.trustRegion.R2;
                        t{3,3} = app.Measurements(sel(1)).KKfit.trustRegion.R2adjusted;
                        t{3,4} = 'Trust-Region-Reflexive';
                        app.UIKKFittingTable.Data = t;
                    end
                    % update dropdown tables
                    app.FittingMethodDropDown.ValueIndex = 2;
                    app.FittingMethodDropDownValueChanged;
                end

            end
            app.KKPlotSelectedDataButton.Value = 0;
        end

        % Value changed function: FitKKonselecteddataButton
        function FitKKonselecteddataButtonValueChanged(app, event)
            value = app.FitKKonselecteddataButton.Value;
            if value
                % get selection
                sel=app.UITableImportedData_EC.Selection;
                KKfitInputs = struct();
                if ~isempty(sel)
                    % fit KK
                    for i = 1:length(sel)
                        [KKfitInputs(i).func, KKfitInputs(i).x0, KKfitInputs(i).lb, KKfitInputs(i).ub, KKfitInputs(i).Variables] = ...
                            KKfunc(app.Measurements(sel(i)).data);
                        
                        app.Measurements(sel(i)).KKfit = fitZ( app.Measurements(sel(i)).data.Zreal + 1i.*app.Measurements(sel(i)).data.Zimag,...
                            app.Measurements(sel(i)).data.Freq, KKfitInputs(i).func, KKfitInputs(i).x0, KKfitInputs(i).lb, KKfitInputs(i).ub );

                        % % extra fit interation using best fit parameters as
                        % % guess
                        % % find best fit coefficients
                        % gof = [app.Measurements(sel(i)).KKfit.simplex.gof app.Measurements(sel(i)).KKfit.levenbergMarquardt.gof ...
                        %     app.Measurements(sel(i)).KKfit.trustRegion.gof];
                        % params = {app.Measurements(sel(i)).KKfit.simplex.coeff, app.Measurements(sel(i)).KKfit.levenbergMarquardt.coeff, ...
                        %     app.Measurements(sel(i)).KKfit.trustRegion.coeff};
                        % [~, ind] = sort(gof);
                        % % do fit again
                        % app.Measurements(sel(i)).KKfit = fitZ( app.Measurements(sel(i)).data.Zreal + 1i.*app.Measurements(sel(i)).data.Zimag,...
                        %     app.Measurements(sel(i)).data.Freq, KKfitInputs(i).func, params{ind(1)}, KKfitInputs(i).lb, KKfitInputs(i).ub );

                        % get names of variables
                        app.Measurements(sel(i)).KKfit.Variables = KKfitInputs(i).Variables;

                    end
                    % update plot
                    app.ECPlotSelectedDataButton.Value = 1;
                    app.ECPlotSelectedDataButtonValueChanged;
                end
            end
            app.FitKKonselecteddataButton.Value = 0;
        end

        % Value changed function: KKClearplotButton
        function KKClearplotButtonValueChanged(app, event)
            value = app.KKClearplotButton.Value;
            if value
                ClearKKplots(app);
            end
            app.KKClearplotButton.Value = 0;
        end

        % Value changed function: FittingMethodDropDown
        function FittingMethodDropDownValueChanged(app, event)
            value = app.FittingMethodDropDown.Value;
            sel=app.UITableImportedData_KK.Selection;
            if ~isempty(sel) && isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit)
                %update parameters table
                M = length(app.Measurements(sel(1)).KKfit.Variables);
                t = cell(M,5);
                for j = 1:M
                    t{j,1} = app.Measurements(sel(1)).KKfit.Variables{j};
                    if strcmp(value, 'Initial Guess')
                        t{j,2} = app.Measurements(sel(1)).KKfit.v0(j);
                    elseif strcmp(value, 'Simplex')
                        t{j,2} = app.Measurements(sel(1)).KKfit.simplex.coeff(j);
                    elseif strcmp(value, 'Levenberg-Marquardt')
                        t{j,2} = app.Measurements(sel(1)).KKfit.levenbergMarquardt.coeff(j);
                    elseif strcmp(value, 'Trust-Region-Reflective')
                        t{j,2} = app.Measurements(sel(1)).KKfit.trustRegion.coeff(j);
                    end
                    t{j,4} = app.Measurements(sel(1)).KKfit.ub(j);
                    t{j,5} = app.Measurements(sel(1)).KKfit.lb(j);
                end
                app.UIFitParameterTable.Data=t;

                % update exit message
                if strcmp(value, 'Initial Guess')
                    app.ExitMessageTextArea.Value = '';
                elseif strcmp(value, 'Simplex')
                    app.ExitMessageTextArea.Value = app.Measurements(sel(1)).KKfit.simplex.output.message;
                elseif strcmp(value, 'Levenberg-Marquardt')
                    app.ExitMessageTextArea.Value = app.Measurements(sel(1)).KKfit.levenbergMarquardt.output.message;
                elseif strcmp(value, 'Trust-Region-Reflective')
                    app.ExitMessageTextArea.Value = app.Measurements(sel(1)).KKfit.trustRegion.output.message;
                end

                % update output table
                t=cell(3,9);
                fit = app.Measurements(sel(1)).KKfit.simplex;
                t{1,1} = fit.output.algorithm;
                t{1,2} = [];
                t{1,3} = fit.output.iterations;
                t{1,4} = fit.output.funcCount;
                t{1,5} = [];
                t{1,6} = fit.exitflag;
                t{1,7} = fit.R2;
                t{1,8} = [];
                t{1,9} = fit.timeElapsed;
                fit = app.Measurements(sel(1)).KKfit.levenbergMarquardt;
                t{2,1} = fit.output.algorithm;
                t{2,2} = fit.output.firstorderopt;
                t{2,3} = fit.output.iterations;
                t{2,4} = fit.output.funcCount;
                t{2,5} = fit.output.cgiterations;
                t{2,6} = fit.exitflag;
                t{2,7} = fit.R2;
                t{2,8} = fit.output.stepsize;
                t{2,9} = fit.timeElapsed;
                fit = app.Measurements(sel(1)).KKfit.trustRegion;
                t{3,1} = fit.output.algorithm;
                t{3,2} = fit.output.firstorderopt;
                t{3,3} = fit.output.iterations;
                t{3,4} = fit.output.funcCount;
                t{3,5} = fit.output.cgiterations;
                t{3,6} = fit.exitflag;
                t{3,7} = fit.R2;
                t{3,8} = fit.output.stepsize;
                t{3,9} = fit.timeElapsed;
                app.UITableOutputTable.Data = t;
            end
        end

        % Value changed function: KKLogNyquistCheckBox
        function KKLogNyquistCheckBoxValueChanged(app, event)
            value = app.KKLogNyquistCheckBox.Value;
            if value
                set(app.UIAxesKKNyquist,'YScale','log','XScale','log')
            else
                set(app.UIAxesKKNyquist,'YScale','linear','XScale','linear')
            end
        end

        % Value changed function: ECPlotSelectedDataButton
        function ECPlotSelectedDataButtonValueChanged(app, event)
            value = app.ECPlotSelectedDataButton.Value;
            tabIdx = app.FitCircuitDropDown.ValueIndex-1;
            if value
                
                % get selection
                sel=app.UITableImportedData_EC.Selection;
                sel=sel(:,1);
                
                if ~isempty(sel)
                    % % update drop down selection for circuits
                    % str = "Kramers-Kronig Fit";
                    % if isfield(app.Measurements(sel(1)), 'CircuitGuess') && isstruct(app.Measurements(sel(1)).CircuitGuess)
                    %     for m = 1:length(app.Measurements(sel(1)).CircuitGuess)
                    %         str = [str; app.Measurements(sel(1)).CircuitGuess(m).String];
                    %     end
                    %    if k+1 > length(app.Measurements(sel(1)).CircuitGuess)
                    %         app.FitCircuitDropDown.ValueIndex = 2;
                    %         k = 1;
                    %     end
                    %     %app.FitCircuitDropDown.Value = str(1);
                    % end
                    % app.FitCircuitDropDown.Items = str;
                    % clear plots
                    fitsel = app.UITableECFits.Selection;
                    fitsel = fitsel';
                    fitItems = "Kramers-Kronig Fit";
                    for i = 1:length(fitsel)
                        fitItems(end+1) = app.Measurements(sel(1)).CircuitGuess(fitsel(i)).String(1);
                    end
                    app.FitCircuitDropDown.Items = fitItems;

                    app.ECClearplotButton.Value = 1;
                    app.ECClearplotButtonValueChanged
                    % check log-spaced nyquist button
                    if app.ECLogNyquistCheckBox.Value
                        % plot nyquist
                        loglog(app.UIAxesECNyquist,app.Measurements(sel(1)).data.Zreal, -app.Measurements(sel(1)).data.Zimag, '.','DisplayName','Data');
                        hold(app.UIAxesECNyquist,'on')
                        % plot fits if they exist
                        if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit) && app.PlotKKfitsCheckBox.Value
                        loglog(app.UIAxesECNyquist,real(app.Measurements(sel(1)).KKfit.simplex.fitcurve),...
                        -imag(app.Measurements(sel(1)).KKfit.simplex.fitcurve), '-','DisplayName','K-K Simplex');
                        loglog(app.UIAxesECNyquist,real(app.Measurements(sel(1)).KKfit.levenbergMarquardt.fitcurve),...
                        -imag(app.Measurements(sel(1)).KKfit.levenbergMarquardt.fitcurve), '--','DisplayName','K-K Levenberg-Marquardt');
                        loglog(app.UIAxesECNyquist,real(app.Measurements(sel(1)).KKfit.trustRegion.fitcurve),...
                        -imag(app.Measurements(sel(1)).KKfit.trustRegion.fitcurve), '-.k','DisplayName','K-K Trust-Region-Reflexive');
                        end
                        if ~isempty(fitsel)
                            for k = 1:length(fitsel)
                                if isfield(app.Measurements(sel(1)), 'CircuitGuess') && isstruct(app.Measurements(sel(1)).CircuitGuess) && strcmp(app.UITableECFits.Data{fitsel(k),1}, app.Measurements(sel(1)).CircuitGuess(fitsel(k)).String)
                                    loglog(app.UIAxesECNyquist,real(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.simplex.fitcurve),...
                                        -imag(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.simplex.fitcurve), '-','DisplayName','Simplex');
                                    loglog(app.UIAxesECNyquist,real(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.levenbergMarquardt.fitcurve),...
                                        -imag(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.levenbergMarquardt.fitcurve), '--','DisplayName','Levenberg-Marquardt');
                                    loglog(app.UIAxesECNyquist,real(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.trustRegion.fitcurve),...
                                        -imag(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.trustRegion.fitcurve), '-.k','DisplayName','Trust-Region-Reflexive');
                                end
                            end
                        end
                    else
                        % plot nyquist
                        plot(app.UIAxesECNyquist,app.Measurements(sel(1)).data.Zreal, -app.Measurements(sel(1)).data.Zimag, '.','DisplayName','Data');
                        hold(app.UIAxesECNyquist,'on')
                        % plot fits if they exist
                        if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit) && app.PlotKKfitsCheckBox.Value
                            plot(app.UIAxesECNyquist,real(app.Measurements(sel(1)).KKfit.simplex.fitcurve),...
                                -imag(app.Measurements(sel(1)).KKfit.simplex.fitcurve), '-','DisplayName','K-K Simplex');
                            plot(app.UIAxesECNyquist,real(app.Measurements(sel(1)).KKfit.levenbergMarquardt.fitcurve),...
                                -imag(app.Measurements(sel(1)).KKfit.levenbergMarquardt.fitcurve), '--','DisplayName','K-K Levenberg-Marquardt');
                            plot(app.UIAxesECNyquist,real(app.Measurements(sel(1)).KKfit.trustRegion.fitcurve),...
                                -imag(app.Measurements(sel(1)).KKfit.trustRegion.fitcurve), '-.k','DisplayName','K-K Trust-Region-Reflexive');
                        end
                        if ~isempty(fitsel)
                            for k = 1:length(fitsel)
                                if isfield(app.Measurements(sel(1)), 'CircuitGuess') && isstruct(app.Measurements(sel(1)).CircuitGuess) && strcmp(app.UITableECFits.Data{fitsel(k),1}, app.Measurements(sel(1)).CircuitGuess(fitsel(k)).String)
                                    plot(app.UIAxesECNyquist,real(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.simplex.fitcurve),...
                                        -imag(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.simplex.fitcurve), '-','DisplayName','Simplex');
                                    plot(app.UIAxesECNyquist,real(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.levenbergMarquardt.fitcurve),...
                                        -imag(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.levenbergMarquardt.fitcurve), '--','DisplayName','Levenberg-Marquardt');
                                    plot(app.UIAxesECNyquist,real(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.trustRegion.fitcurve),...
                                        -imag(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.trustRegion.fitcurve), '-.k','DisplayName','Trust-Region-Reflexive');
                                end
                            end
                        end
                    end
                    hold(app.UIAxesECNyquist,'off')
                    grid(app.UIAxesECNyquist,'on')
                    xlabel(app.UIAxesECNyquist,'Real(Z)');
                    ylabel(app.UIAxesECNyquist,'-Imag(Z)');
                    legend(app.UIAxesECNyquist,'Location','south');
                    
                    % plot Zmod
                    loglog(app.UIAxesECMod,app.Measurements(sel(1)).data.Freq, app.Measurements(sel(1)).data.Zmod, '.','DisplayName','Data');
                    hold(app.UIAxesECMod,'on')
                    % plot fits if they exist
                    if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit) && app.PlotKKfitsCheckBox.Value
                        loglog(app.UIAxesECMod,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).KKfit.simplex.fitcurve), '-','DisplayName','K-K Simplex');
                        loglog(app.UIAxesECMod,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).KKfit.levenbergMarquardt.fitcurve), '--','DisplayName','K-K Levenberg-Marquardt');
                        loglog(app.UIAxesECMod,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).KKfit.trustRegion.fitcurve), '-.k','DisplayName','K-K Trust-Region-Reflexive');
                    end
                    if ~isempty(fitsel)
                        for k = 1:length(fitsel)
                            if isfield(app.Measurements(sel(1)), 'CircuitGuess') && isstruct(app.Measurements(sel(1)).CircuitGuess) && strcmp(app.UITableECFits.Data{fitsel(k),1}, app.Measurements(sel(1)).CircuitGuess(fitsel(k)).String)
                                loglog(app.UIAxesECMod,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.simplex.fitcurve), '-','DisplayName','Simplex');
                                loglog(app.UIAxesECMod,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.levenbergMarquardt.fitcurve), '--','DisplayName','Levenberg-Marquardt');
                                loglog(app.UIAxesECMod,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.trustRegion.fitcurve), '-.k','DisplayName','Trust-Region-Reflexive');
                            end
                        end
                    end
                    hold(app.UIAxesECMod,'off')
                    grid(app.UIAxesECMod,'on')
                    xlim(app.UIAxesECMod,[app.Measurements(sel(1)).data.Freq(end) app.Measurements(sel(1)).data.Freq(1)]);
                    xlabel(app.UIAxesECMod,'Frequency (Hz)');
                    ylabel(app.UIAxesECMod,'Impedance Magnitude |Z| (ohms)');
                    legend(app.UIAxesECMod,'Location','northeast');

                    % plot Z phase
                    semilogx(app.UIAxesECPhase,app.Measurements(sel(1)).data.Freq, app.Measurements(sel(1)).data.Zphz, '.','DisplayName','Data');
                    hold(app.UIAxesECPhase,'on')
                    if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit) && app.PlotKKfitsCheckBox.Value
                        semilogx(app.UIAxesECPhase,app.Measurements(sel(1)).data.Freq, 180/pi*angle(app.Measurements(sel(1)).KKfit.simplex.fitcurve), '-','DisplayName','K-K Simplex');
                        semilogx(app.UIAxesECPhase,app.Measurements(sel(1)).data.Freq, 180/pi*angle(app.Measurements(sel(1)).KKfit.levenbergMarquardt.fitcurve), '--','DisplayName','K-K Levenberg-Marquardt');
                        semilogx(app.UIAxesECPhase,app.Measurements(sel(1)).data.Freq, 180/pi*angle(app.Measurements(sel(1)).KKfit.trustRegion.fitcurve), '-.k','DisplayName','K-K Trust-Region-Reflexive');
                    end
                    if ~isempty(fitsel)
                        for k = 1:length(fitsel)
                            if isfield(app.Measurements(sel(1)), 'CircuitGuess') && isstruct(app.Measurements(sel(1)).CircuitGuess) && strcmp(app.UITableECFits.Data{fitsel(k),1}, app.Measurements(sel(1)).CircuitGuess(fitsel(k)).String)
                                semilogx(app.UIAxesECPhase,app.Measurements(sel(1)).data.Freq, 180/pi*angle(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.simplex.fitcurve), '-','DisplayName','Simplex');
                                semilogx(app.UIAxesECPhase,app.Measurements(sel(1)).data.Freq, 180/pi*angle(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.levenbergMarquardt.fitcurve), '--','DisplayName','Levenberg-Marquardt');
                                semilogx(app.UIAxesECPhase,app.Measurements(sel(1)).data.Freq, 180/pi*angle(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.trustRegion.fitcurve), '-.k','DisplayName','Trust-Region-Reflexive');
                            end
                        end
                    end
                    hold(app.UIAxesECPhase,'off')
                    grid(app.UIAxesECPhase,'on')
                    xlim(app.UIAxesECPhase,[app.Measurements(sel(1)).data.Freq(end) app.Measurements(sel(1)).data.Freq(1)]);
                    xlabel(app.UIAxesECPhase,'Frequency (Hz)');
                    ylabel(app.UIAxesECPhase,'Phase (degrees)');
                    legend(app.UIAxesECPhase,'Location','north');

                    % plot error
                    if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit) && app.PlotKKfitsCheckBox.Value
                        hold(app.UIAxesECError,'on')
                        loglog(app.UIAxesECError,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).KKfit.simplex.residuals), '-','DisplayName','K-K Simplex');
                        loglog(app.UIAxesECError,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).KKfit.levenbergMarquardt.residuals), '--','DisplayName','K-K Levenberg-Marquardt');
                        loglog(app.UIAxesECError,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).KKfit.trustRegion.residuals), '-.k','DisplayName','K-K Trust-Region-Reflexive');
                        hold(app.UIAxesECError,'off')
                        grid(app.UIAxesECError,'on')
                        xlim(app.UIAxesECError,[app.Measurements(sel(1)).data.Freq(end) app.Measurements(sel(1)).data.Freq(1)]);
                        xlabel(app.UIAxesECError,'Frequency (Hz)');
                        ylabel(app.UIAxesECError,'Weighted Residuals');
                        legend(app.UIAxesECError,'Location','northeast');
                        set(app.UIAxesECError, 'YScale', 'log','XScale', 'log');
                    end
                    if ~isempty(fitsel)
                        for k = 1:length(fitsel)
                            % plot error
                            if isfield(app.Measurements(sel(1)), 'CircuitGuess') && isstruct(app.Measurements(sel(1)).CircuitGuess) && strcmp(app.UITableECFits.Data{fitsel(k),1}, app.Measurements(sel(1)).CircuitGuess(fitsel(k)).String)
                                hold(app.UIAxesECError,'on')
                                loglog(app.UIAxesECError,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.simplex.residuals), '-','DisplayName','Simplex');
                                loglog(app.UIAxesECError,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.levenbergMarquardt.residuals), '--','DisplayName','Levenberg-Marquardt');
                                loglog(app.UIAxesECError,app.Measurements(sel(1)).data.Freq, abs(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.trustRegion.residuals), '-.k','DisplayName','Trust-Region-Reflexive');
                                hold(app.UIAxesECError,'off')
                                grid(app.UIAxesECError,'on')
                                xlim(app.UIAxesECError,[app.Measurements(sel(1)).data.Freq(end) app.Measurements(sel(1)).data.Freq(1)]);
                                xlabel(app.UIAxesECError,'Frequency (Hz)');
                                ylabel(app.UIAxesECError,'Weighted Residuals');
                                legend(app.UIAxesECError,'Location','northeast');
                                set(app.UIAxesECError, 'YScale', 'log','XScale', 'log');
                            end
                            % plot EC fit values
                            if isfield(app.Measurements(sel(1)), 'CircuitGuess') && isstruct(app.Measurements(sel(1)).CircuitGuess) && strcmp(app.UITableECFits.Data{fitsel(k),1}, app.Measurements(sel(1)).CircuitGuess(fitsel(k)).String)
                                l=length(app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.v0);
                                scatter(app.UIAxesParameters,1:l, app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.simplex.coeff, '.','DisplayName','Simplex');
                                hold(app.UIAxesParameters,'on')
                                scatter(app.UIAxesParameters,1:l, app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.levenbergMarquardt.coeff, 'o','MarkerEdgeColor','r','DisplayName','Levenberg-Marquardt');
                                scatter(app.UIAxesParameters,1:l, app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.trustRegion.coeff, 'd','MarkerEdgeColor','g','DisplayName','Trust-Region-Reflexive');
                                scatter(app.UIAxesParameters,1:l, app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.v0, 'x','MarkerEdgeColor','k','DisplayName','Initial Guess')
                                hold(app.UIAxesParameters,'off')
                                grid(app.UIAxesParameters,'on')
                                set(app.UIAxesParameters, 'YScale', 'log');
                                
                                xlim(app.UIAxesParameters,[1 l]);
                                %xlabel(app.UIAxesKKError,'Frequency (Hz)');
                                %ylabel(app.UIAxesKKError,'Weighted Residuals');
                                legend(app.UIAxesParameters,'Location','northeast');
                            end
                        end
                    end
                    
                    % update gof table
                    t = {};
                    if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit) && app.PlotKKfitsCheckBox.Value
                        t{1,1} = app.Measurements(sel(1)).KKfit.simplex.gof;
                        t{1,2} = app.Measurements(sel(1)).KKfit.simplex.R2;
                        t{1,3} = app.Measurements(sel(1)).KKfit.simplex.R2adjusted;
                        t{1,4} = 'Simplex';
                        t{2,1} = app.Measurements(sel(1)).KKfit.levenbergMarquardt.gof;
                        t{2,2} = app.Measurements(sel(1)).KKfit.levenbergMarquardt.R2;
                        t{2,3} = app.Measurements(sel(1)).KKfit.levenbergMarquardt.R2adjusted;
                        t{2,4} = 'Levenberg-Marquardt';
                        t{3,1} = app.Measurements(sel(1)).KKfit.trustRegion.gof;
                        t{3,2} = app.Measurements(sel(1)).KKfit.trustRegion.R2;
                        t{3,3} = app.Measurements(sel(1)).KKfit.trustRegion.R2adjusted;
                        t{3,4} = 'Trust-Region-Reflexive';
                    end
                    if ~isempty(fitsel)
                        for k = 1:length(fitsel)
                            if isfield(app.Measurements(sel(1)), 'CircuitGuess') && isstruct(app.Measurements(sel(1)).CircuitGuess) && strcmp(app.UITableECFits.Data{fitsel(k),1}, app.Measurements(sel(1)).CircuitGuess(fitsel(k)).String)
                                t{end+1,1} = app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.simplex.gof;
                                t{end,2} = app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.simplex.R2;
                                t{end,3} = app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.simplex.R2adjusted;
                                t{end,4} = 'Simplex';
                                t{end+1,1} = app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.levenbergMarquardt.gof;
                                t{end,2} = app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.levenbergMarquardt.R2;
                                t{end,3} = app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.levenbergMarquardt.R2adjusted;
                                t{end,4} = 'Levenberg-Marquardt';
                                t{end+1,1} = app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.trustRegion.gof;
                                t{end,2} = app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.trustRegion.R2;
                                t{end,3} = app.Measurements(sel(1)).CircuitGuess(fitsel(k)).fit.trustRegion.R2adjusted;
                                t{end,4} = 'Trust-Region-Reflexive';
                            end
                        end
                    end
                    app.UIECFittingGoFTable.Data = t;
                    app.UIECFittingGoFTable.ColumnFormat = {'shortE' '' '' ''};
                    values = 0;
                    parameters = 0;
                    error = 0;
                    % update parameter value table
                    if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit) && tabIdx == 0
                        parameters = app.Measurements(sel(1)).KKfit.Variables;
                        values = app.Measurements(sel(1)).KKfit.levenbergMarquardt.coeff;
                        for j = 1:length(app.Measurements(sel(1)).KKfit.Variables)
                            error(j,1) = 0.5.*(app.Measurements(sel(1)).KKfit.levenbergMarquardt.coeffCI(j,2)-...
                                app.Measurements(sel(1)).KKfit.levenbergMarquardt.coeffCI(j,1));
                        end
                    end
                    if isfield(app.Measurements(sel(1)), 'CircuitGuess') && isstruct(app.Measurements(sel(1)).CircuitGuess) && length(app.Measurements(sel(1)).CircuitGuess) >= tabIdx && tabIdx > 0
                        parameters = app.Measurements(sel(1)).CircuitGuess(fitsel(tabIdx)).Variables;
                        values = app.Measurements(sel(1)).CircuitGuess(fitsel(tabIdx)).fit.levenbergMarquardt.coeff;
                        for j = 1:length(app.Measurements(sel(1)).CircuitGuess(fitsel(tabIdx)).Variables)
                            error(j,1) = 0.5.*(app.Measurements(sel(1)).CircuitGuess(fitsel(tabIdx)).fit.levenbergMarquardt.coeffCI(j,2)-...
                                app.Measurements(sel(1)).CircuitGuess(fitsel(tabIdx)).fit.levenbergMarquardt.coeffCI(j,1));
                        end
                    end
                    parameters = parameters(:);
                    units = zeros(length(values),1);
                    % values
                    % debugging why some values are displayed as zero
                    % in the UItable but are just small. it is failing
                    % to use shortE formating in the UItable
                    t = table(parameters,values,error,units);
                    app.UIECParameterTable.Data = t;
                    app.UIECParameterTable.ColumnFormat = {'' 'shortE' 'shortE' ''};
                    drawnow;
                end

            end
            app.ECPlotSelectedDataButton.Value = 0;
        end

        % Value changed function: ECClearplotButton
        function ECClearplotButtonValueChanged(app, event)
            value = app.ECClearplotButton.Value;
            if value
                ClearECplots(app);
            end
            app.ECClearplotButton.Value = 0;
        end

        % Value changed function: ECLogNyquistCheckBox
        function ECLogNyquistCheckBoxValueChanged(app, event)
            value = app.ECLogNyquistCheckBox.Value;
            if value
                set(app.UIAxesECNyquist,'YScale','log','XScale','log')
            else
                set(app.UIAxesECNyquist,'YScale','linear','XScale','linear')
            end
        end

        % Value changed function: ComputeselectedfitsButton
        function ComputeselectedfitsButtonValueChanged(app, event)
            value = app.ComputeselectedfitsButton.Value;
            if value
                % get selection
                sel=app.UITableImportedData_EC.Selection;
                sel=sel(:,1);
                fitsel = app.UITableECFits.Selection;
                fitsel = fitsel';
                FitCirc = struct();
                % Get selected circuits to fit from table
                for i = 1:length(fitsel)
                    FitCirc(i).String = app.UITableECFits.Data{fitsel(i,1),1};
                end
                % Check if circuits are valid
                for i = 1:length(FitCirc)
                    if isValidCircuitString(FitCirc(i).String) || true
                        disp([FitCirc(i).String ' is a valid circuit'])
                        FitCirc(i).String = getCanonicalForm(parseCircuitString(FitCirc(i).String));
                    else
                        warning([FitCirc(i).String ' is not a valid circuit. Replacing it with a resistor.'])
                        % use a resistor as a dummy replacement
                        FitCirc(i).String = "R";
                    end
                end
                % Convert circuits strings to function handles
                for i = 1:length(FitCirc)
                    [FitCirc(i).Func, FitCirc(i).Variables] = CirStr2FuncHan(FitCirc(i).String{1});
                end
                % Do fitting of selection with ECs
                if ~isempty(sel)
                    % Construct custom fit options struct
                    options = struct();
                    options.MaxIter = app.MaxIterationsEditField.Value;
                    options.MaxFunEval = app.MaxFuncEvaluationsperparameterEditField.Value;
                    options.Tol = app.ToleranceEditField.Value;
                    options.SimpFactor = app.SimplexMultiplierEditField.Value;
                    options.UpperFreq = app.UpperFittingFrequencyEditField.Value;
                    options.LowerFreq = app.LowerFittingFrequencyEditField.Value;
                    if app.ShowIterationsoffittingCheckBox.Value
                        options.display = 'iter';
                    else
                        options.display = 'final';
                    end
                    options.SimplexSeed = app.UseSimplexFittoSeedothermethodsCheckBox.Value;
                    % fit selection
                    for i = 1:length(sel)
                        Zdata = app.Measurements(sel(i)).data.Zreal+1j.*app.Measurements(sel(i)).data.Zimag;
                        freq = app.Measurements(sel(i)).data.Freq;
                        for k = 1:length(FitCirc)
                            [v0,lb,ub] = getInitialGuess(FitCirc(k).Variables,1,1);
                            app.Measurements(sel(i)).CircuitGuess(fitsel(k)).Func = FitCirc(k).Func;
                            app.Measurements(sel(i)).CircuitGuess(fitsel(k)).Variables = FitCirc(k).Variables;
                            app.Measurements(sel(i)).CircuitGuess(fitsel(k)).String = FitCirc(k).String;
                            fit1 = fitZ(Zdata, freq,FitCirc(k).Func,v0,lb,ub,options);
                            % extra fit interation using best fit parameters as
                            % guess
                            % find best fit coefficients
                            gof1 = [fit1.simplex.gof fit1.levenbergMarquardt.gof ...
                                fit1.trustRegion.gof];
                            params1 = {fit1.simplex.coeff, fit1.levenbergMarquardt.coeff, ...
                                fit1.trustRegion.coeff};
                            [~, ind] = sort(gof1);
                            % do fit again
                            fit2= fitZ(Zdata,freq, FitCirc(k).Func,params1{ind(1)},lb,ub,options);
                            gof2 = [fit2.simplex.gof fit2.levenbergMarquardt.gof ...
                                fit2.trustRegion.gof];
                            if min(gof1) > min(gof2)
                                app.Measurements(sel(i)).CircuitGuess(fitsel(k)).fit = fit2;
                            else
                                app.Measurements(sel(i)).CircuitGuess(fitsel(k)).fit = fit1;
                            end
                        end
                        
                    end
                    % compute KK fit
                    if app.PlotKKfitsCheckBox.Value
                        app.FitKKonselecteddataButton.Value = 1;
                        app.FitKKonselecteddataButtonValueChanged;
                    end
                    % update plot
                    app.ECPlotSelectedDataButton.Value = 1;
                    app.ECPlotSelectedDataButtonValueChanged;
                end
            end
            assignin('base','app',app);
            app.ComputeselectedfitsButton.Value = 0;
        end

        % Value changed function: FitCircuitDropDown
        function FitCircuitDropDownValueChanged(app, event)
            value = app.FitCircuitDropDown.Value;
            % update plots and tables to show fit of that circuit
            % update plot
                    %app.ECPlotSelectedDataButton.Value = 1;
                    %app.ECPlotSelectedDataButtonValueChanged;
            if value
                tabIdx = app.FitCircuitDropDown.ValueIndex-1;
                sel=app.UITableImportedData_EC.Selection;
                fitsel = app.UITableECFits.Selection;
                parameters = [];
                values = [];
                error = [];
                if ~isempty(sel)
                    % update parameter value table
                    if isfield(app.Measurements(sel(1)), 'KKfit') && isstruct(app.Measurements(sel(1)).KKfit) && tabIdx == 0
                        parameters = app.Measurements(sel(1)).KKfit.Variables;
                        values = app.Measurements(sel(1)).KKfit.levenbergMarquardt.coeff;
                        for j = 1:length(app.Measurements(sel(1)).KKfit.Variables)
                            error(j,1) = 0.5.*(app.Measurements(sel(1)).KKfit.levenbergMarquardt.coeffCI(j,2)-...
                                app.Measurements(sel(1)).KKfit.levenbergMarquardt.coeffCI(j,1));
                        end
                        parameters = parameters(:);
                    end
                    if isfield(app.Measurements(sel(1)), 'CircuitGuess') && isstruct(app.Measurements(sel(1)).CircuitGuess) && length(app.Measurements(sel(1)).CircuitGuess) >= tabIdx && tabIdx > 0
                        parameters = app.Measurements(sel(1)).CircuitGuess(fitsel(tabIdx)).Variables;
                        values = app.Measurements(sel(1)).CircuitGuess(fitsel(tabIdx)).fit.levenbergMarquardt.coeff;
                        for j = 1:length(app.Measurements(sel(1)).CircuitGuess(fitsel(tabIdx)).Variables)
                            error(j,1) = 0.5.*(app.Measurements(sel(1)).CircuitGuess(fitsel(tabIdx)).fit.levenbergMarquardt.coeffCI(j,2)-...
                                app.Measurements(sel(1)).CircuitGuess(fitsel(tabIdx)).fit.levenbergMarquardt.coeffCI(j,1));
                        end
                        parameters = parameters(:);
                    end
                    units = zeros(length(values),1);
                    % values
                    % debugging why some values are displayed as zero
                    % in the UItable but are just small. it is failing
                    % to use shortE formating in the UItable
                    t = table(parameters,values,error,units);
                    app.UIECParameterTable.Data = t;
                    app.UIECParameterTable.ColumnFormat = {'' 'shortE' 'shortE' ''};
                    drawnow;
                end
            end
        end

        % Value changed function: AddtolistButton
        function AddtolistButtonValueChanged(app, event)
            value = app.AddtolistButton.Value;
            
        end

        % Value changed function: UseasHighImpedanceMeasurementButton
        function UseasHighImpedanceMeasurementButtonValueChanged(app, event)
            value = app.UseasHighImpedanceMeasurementButton.Value;
            % add selected data row to be the high impedance spectrum
            % global value
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImportDataTab = uitab(app.TabGroup);
            app.ImportDataTab.Title = 'Import Data';
            app.TabGroup2 = uitabgroup(app.ImportDataTab);
            app.FolderFilesTab = uitab(app.TabGroup2);
            app.FolderFilesTab.Title = 'Folder Files';
            app.UITableFolderFiles = uitable(app.FolderFilesTab);
            app.UITableFolderFiles.ColumnName = {'Filename'; 'Date Created'; 'Folder path'; 'Full Path'};
            app.UITableFolderFiles.ColumnRearrangeable = 'on';
            app.UITableFolderFiles.RowName = {};
            app.UITableFolderFiles.ColumnSortable = [true true true false];
            app.UITableFolderFiles.SelectionType = 'row';
            app.UITableFolderFiles.SelectionChangedFcn = createCallbackFcn(app, @UITableFolderFilesSelectionChanged, true);
            app.FindfoldertoimportButton = uibutton(app.FolderFilesTab, 'state');
            app.FindfoldertoimportButton.ValueChangedFcn = createCallbackFcn(app, @FindfoldertoimportButtonValueChanged, true);
            app.FindfoldertoimportButton.Text = 'Find folder to import';
            app.ImportselectedfilesButton = uibutton(app.FolderFilesTab, 'state');
            app.ImportselectedfilesButton.ValueChangedFcn = createCallbackFcn(app, @ImportselectedfilesButtonValueChanged, true);
            app.ImportselectedfilesButton.Text = 'Import selected files';
            app.SaveAppStatewithalldataButton = uibutton(app.FolderFilesTab, 'state');
            app.SaveAppStatewithalldataButton.Text = 'Save App State with all data';
            app.LoadAppstatewithalldataButton = uibutton(app.FolderFilesTab, 'state');
            app.LoadAppstatewithalldataButton.Text = 'Load App state with all data';
            app.ImportedDataTab = uitab(app.TabGroup2);
            app.ImportedDataTab.Title = 'Imported Data';
            app.UITableImportedData = uitable(app.ImportedDataTab);
            app.UITableImportedData.ColumnName = {'Filename'; 'Date'; 'Lowest Frequency'; 'Vac'};
            app.UITableImportedData.RowName = {};
            app.UITableImportedData.ColumnSortable = true;
            app.UITableImportedData.SelectionType = 'row';
            app.UITableImportedData.ColumnEditable = [true false false false];
            app.FittingTab = uitab(app.TabGroup);
            app.FittingTab.Title = 'Fitting';
            app.FittingTabGroup = uitabgroup(app.FittingTab);
            app.KKFittingTab = uitab(app.FittingTabGroup);
            app.KKFittingTab.Title = 'KK Fitting';
            app.UIAxesKKNyquist = uiaxes(app.KKFittingTab);
            title(app.UIAxesKKNyquist, 'Nyquist')
            xlabel(app.UIAxesKKNyquist, 'Real Z')
            ylabel(app.UIAxesKKNyquist, '- Imaginary Z')
            zlabel(app.UIAxesKKNyquist, 'Z')
            app.UIAxesKKError = uiaxes(app.KKFittingTab);
            title(app.UIAxesKKError, 'Error')
            xlabel(app.UIAxesKKError, 'Frequency')
            ylabel(app.UIAxesKKError, 'Residuals')
            zlabel(app.UIAxesKKError, 'Z')
            app.UIAxesKKMod = uiaxes(app.KKFittingTab);
            title(app.UIAxesKKMod, 'Modulus')
            xlabel(app.UIAxesKKMod, 'Frequency (Hz)')
            ylabel(app.UIAxesKKMod, '| Z | (ohms)')
            zlabel(app.UIAxesKKMod, 'Z')
            app.UIAxesKKPhase = uiaxes(app.KKFittingTab);
            title(app.UIAxesKKPhase, 'Phase')
            xlabel(app.UIAxesKKPhase, 'Frequency (Hz)')
            ylabel(app.UIAxesKKPhase, '\theta (degrees)')
            zlabel(app.UIAxesKKPhase, 'Z')
            app.UIKKFittingTable = uitable(app.KKFittingTab);
            app.UIKKFittingTable.ColumnName = {'GoF'; 'R2'; 'R2Adjusted'; 'Method'};
            app.UIKKFittingTable.RowName = {};
            app.KKFittingTabGroup = uitabgroup(app.KKFittingTab);
            app.KKDataSelectionTab = uitab(app.KKFittingTabGroup);
            app.KKDataSelectionTab.Title = 'Data Selection';
            app.UITableImportedData_KK = uitable(app.KKDataSelectionTab);
            app.UITableImportedData_KK.ColumnName = {'Filename'; 'Date'; 'Lowest Frequency'; 'Vac'};
            app.UITableImportedData_KK.RowName = {};
            app.UITableImportedData_KK.ColumnSortable = true;
            app.UITableImportedData_KK.SelectionType = 'row';
            app.UITableImportedData_KK.ColumnEditable = [true false false false];
            app.KKPlotSelectedDataButton = uibutton(app.KKDataSelectionTab, 'state');
            app.KKPlotSelectedDataButton.ValueChangedFcn = createCallbackFcn(app, @KKPlotSelectedDataButtonValueChanged, true);
            app.KKPlotSelectedDataButton.Text = 'Plot Selected Data';
            app.KKClearplotButton = uibutton(app.KKDataSelectionTab, 'state');
            app.KKClearplotButton.ValueChangedFcn = createCallbackFcn(app, @KKClearplotButtonValueChanged, true);
            app.KKClearplotButton.Text = 'Clear plot';
            app.KKLogNyquistCheckBox = uicheckbox(app.KKDataSelectionTab);
            app.KKLogNyquistCheckBox.ValueChangedFcn = createCallbackFcn(app, @KKLogNyquistCheckBoxValueChanged, true);
            app.KKLogNyquistCheckBox.Text = 'Log-spaced Nyquist';
            app.FitKKonselecteddataButton = uibutton(app.KKDataSelectionTab, 'state');
            app.FitKKonselecteddataButton.ValueChangedFcn = createCallbackFcn(app, @FitKKonselecteddataButtonValueChanged, true);
            app.FitKKonselecteddataButton.Text = 'Fit KK on selected data';
            app.AdvancedSettingsTab_KK = uitab(app.KKFittingTabGroup);
            app.AdvancedSettingsTab_KK.Title = 'Advanced Settings';
            app.UITable = uitable(app.KKFittingTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.KKFitValuesTab = uitab(app.FittingTabGroup);
            app.KKFitValuesTab.Title = 'KK Fit Values';
            app.UIAxesKKParameters = uiaxes(app.KKFitValuesTab);
            title(app.UIAxesKKParameters, 'KK Parameter values')
            xlabel(app.UIAxesKKParameters, 'Parameter index')
            ylabel(app.UIAxesKKParameters, 'Value')
            zlabel(app.UIAxesKKParameters, 'Z')
            app.EquivilentCircuitFittingTab = uitab(app.FittingTabGroup);
            app.EquivilentCircuitFittingTab.Title = 'Equivilent Circuit Fitting';
            app.UIAxesECNyquist = uiaxes(app.EquivilentCircuitFittingTab);
            title(app.UIAxesECNyquist, 'Nyquist')
            xlabel(app.UIAxesECNyquist, 'Real Z')
            ylabel(app.UIAxesECNyquist, '- Imaginary Z')
            zlabel(app.UIAxesECNyquist, 'Z')
            app.UIAxesECError = uiaxes(app.EquivilentCircuitFittingTab);
            title(app.UIAxesECError, 'Error')
            xlabel(app.UIAxesECError, 'Frequency')
            ylabel(app.UIAxesECError, 'Residuals')
            zlabel(app.UIAxesECError, 'Z')
            app.UIAxesECMod = uiaxes(app.EquivilentCircuitFittingTab);
            title(app.UIAxesECMod, 'Modulus')
            xlabel(app.UIAxesECMod, 'Frequency (Hz)')
            ylabel(app.UIAxesECMod, '| Z | (ohms)')
            zlabel(app.UIAxesECMod, 'Z')
            app.UIAxesECPhase = uiaxes(app.EquivilentCircuitFittingTab);
            title(app.UIAxesECPhase, 'Phase')
            xlabel(app.UIAxesECPhase, 'Frequency (Hz)')
            ylabel(app.UIAxesECPhase, '\theta (degrees)')
            zlabel(app.UIAxesECPhase, 'Z')
            app.ECPlottingTabGroup = uitabgroup(app.EquivilentCircuitFittingTab);
            app.ECDataSelectionTab = uitab(app.ECPlottingTabGroup);
            app.ECDataSelectionTab.Title = 'Data Selection';
            app.UITableImportedData_EC = uitable(app.ECDataSelectionTab);
            app.UITableImportedData_EC.ColumnName = {'Filename'; 'Date'; 'Lowest Frequency'; 'Vac'};
            app.UITableImportedData_EC.RowName = {};
            app.UseasLowImpedanceMeasurementButton = uibutton(app.ECDataSelectionTab, 'state');
            app.UseasLowImpedanceMeasurementButton.Text = {'Use as Low '; 'Impedance Measurement'};
            app.UseasHighImpedanceMeasurementButton = uibutton(app.ECDataSelectionTab, 'state');
            app.UseasHighImpedanceMeasurementButton.ValueChangedFcn = createCallbackFcn(app, @UseasHighImpedanceMeasurementButtonValueChanged, true);
            app.UseasHighImpedanceMeasurementButton.Text = {'Use as High'; 'Impedance Measurement'};
            app.AdvancedTab = uitab(app.ECPlottingTabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.MaxIterationsEditFieldLabel = uilabel(app.AdvancedTab);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations';
            app.MaxIterationsEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.MaxIterationsEditField.Value = 30000;
            app.MaxFuncEvaluationsperparameterLabel = uilabel(app.AdvancedTab);
            app.MaxFuncEvaluationsperparameterLabel.Text = {'Max Func. Evaluations'; ' per parameter'};
            app.MaxFuncEvaluationsperparameterEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.MaxFuncEvaluationsperparameterEditField.Value = 5000;
            app.RandomInitialGuessCheckBox = uicheckbox(app.AdvancedTab);
            app.RandomInitialGuessCheckBox.Text = 'Random Initial Guess';
            app.ToleranceEditFieldLabel = uilabel(app.AdvancedTab);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.ToleranceEditField.Value = 1e-12;
            app.GuessupdateinterationsEditFieldLabel = uilabel(app.AdvancedTab);
            app.GuessupdateinterationsEditFieldLabel.Text = 'Guess update interations';
            app.GuessupdateinterationsEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.GuessupdateinterationsEditField.Value = 2;
            app.SubtractHighImpedanceCheckBox = uicheckbox(app.AdvancedTab);
            app.SubtractHighImpedanceCheckBox.Text = 'Subtract High Impedance';
            app.SubtractLowImpedanceCheckBox = uicheckbox(app.AdvancedTab);
            app.SubtractLowImpedanceCheckBox.Text = 'Subtract Low Impedance';
            app.SimplexMultiplierLabel = uilabel(app.AdvancedTab);
            app.SimplexMultiplierLabel.Text = {'Simplex'; 'Multiplier'};
            app.SimplexMultiplierEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.SimplexMultiplierEditField.Value = 200;
            app.UseSimplexFittoSeedothermethodsCheckBox = uicheckbox(app.AdvancedTab);
            app.UseSimplexFittoSeedothermethodsCheckBox.Text = 'Use Simplex Fit to Seed other methods';
            app.UseSimplexFittoSeedothermethodsCheckBox.Value = true;
            app.ShowIterationsoffittingCheckBox = uicheckbox(app.AdvancedTab);
            app.ShowIterationsoffittingCheckBox.Text = 'Show Iterations of fitting';
            app.ShowIterationsoffittingCheckBox.Value = true;
            app.UpperFittingFrequencyEditFieldLabel = uilabel(app.AdvancedTab);
            app.UpperFittingFrequencyEditFieldLabel.Text = 'Upper Fitting Frequency ';
            app.UpperFittingFrequencyEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.UpperFittingFrequencyEditField.Value = 1000000;
            app.LowerFittingFrequencyEditFieldLabel = uilabel(app.AdvancedTab);
            app.LowerFittingFrequencyEditFieldLabel.Text = 'Lower Fitting Frequency';
            app.LowerFittingFrequencyEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.LowerFittingFrequencyEditField.Value = 0.0001;
            app.OnlyfitwithinthesefrequencyboundsCheckBox = uicheckbox(app.AdvancedTab);
            app.OnlyfitwithinthesefrequencyboundsCheckBox.Text = 'Only fit within these frequency bounds';
            app.UIECFittingGoFTable = uitable(app.EquivilentCircuitFittingTab);
            app.UIECFittingGoFTable.ColumnName = {'GoF'; 'R2'; 'R2Adjusted'; 'Method'};
            app.UIECFittingGoFTable.RowName = {};
            app.UIECParameterTable = uitable(app.EquivilentCircuitFittingTab);
            app.UIECParameterTable.ColumnName = {'Parameter'; 'Value'; 'Error'; 'Units'};
            app.UIECParameterTable.RowName = {};
            app.ECPlotSelectedDataButton = uibutton(app.EquivilentCircuitFittingTab, 'state');
            app.ECPlotSelectedDataButton.ValueChangedFcn = createCallbackFcn(app, @ECPlotSelectedDataButtonValueChanged, true);
            app.ECPlotSelectedDataButton.Text = 'Plot Selected Data';
            app.ECLogNyquistCheckBox = uicheckbox(app.EquivilentCircuitFittingTab);
            app.ECLogNyquistCheckBox.ValueChangedFcn = createCallbackFcn(app, @ECLogNyquistCheckBoxValueChanged, true);
            app.ECLogNyquistCheckBox.Text = 'Log-spaced Nyquist';
            app.ECClearplotButton = uibutton(app.EquivilentCircuitFittingTab, 'state');
            app.ECClearplotButton.ValueChangedFcn = createCallbackFcn(app, @ECClearplotButtonValueChanged, true);
            app.ECClearplotButton.Text = 'Clear plot';
            app.UITableECFits = uitable(app.EquivilentCircuitFittingTab);
            app.UITableECFits.ColumnName = {'Equivalent Circuits'};
            app.UITableECFits.RowName = {};
            app.UITableECFits.SelectionType = 'row';
            app.ComputeselectedfitsButton = uibutton(app.EquivilentCircuitFittingTab, 'state');
            app.ComputeselectedfitsButton.ValueChangedFcn = createCallbackFcn(app, @ComputeselectedfitsButtonValueChanged, true);
            app.ComputeselectedfitsButton.Text = 'Compute selected fits';
            app.PlotKKfitsCheckBox = uicheckbox(app.EquivilentCircuitFittingTab);
            app.PlotKKfitsCheckBox.Text = 'Plot K-K fits';
            app.FitCircuitDropDownLabel = uilabel(app.EquivilentCircuitFittingTab);
            app.FitCircuitDropDownLabel.Text = 'Fit Circuit';
            app.FitCircuitDropDown = uidropdown(app.EquivilentCircuitFittingTab);
            app.FitCircuitDropDown.Items = {'Circuit Model'};
            app.FitCircuitDropDown.ValueChangedFcn = createCallbackFcn(app, @FitCircuitDropDownValueChanged, true);
            app.FitCircuitDropDown.Value = 'Circuit Model';
            app.ECFitValuesTab = uitab(app.FittingTabGroup);
            app.ECFitValuesTab.Title = 'EC Fit Values';
            app.UIAxesParameters = uiaxes(app.ECFitValuesTab);
            title(app.UIAxesParameters, 'EC Parameter values')
            xlabel(app.UIAxesParameters, 'Parameter index')
            ylabel(app.UIAxesParameters, 'Value')
            zlabel(app.UIAxesParameters, 'Z')
            app.ExitMessageTextAreaLabel = uilabel(app.ECFitValuesTab);
            app.ExitMessageTextAreaLabel.Text = 'Exit Message';
            app.ExitMessageTextArea = uitextarea(app.ECFitValuesTab);
            app.ExitMessageTextArea.Editable = 'off';
            app.UITableOutputTable = uitable(app.ECFitValuesTab);
            app.UITableOutputTable.ColumnName = {'Algorithm'; 'First Order Opt'; 'Iterations'; 'Func Evals'; 'CGI Iterations'; 'Exit flag'; 'R2'; 'stepsize'; 'Time Elapsed'};
            app.UITableOutputTable.ColumnRearrangeable = 'on';
            app.UITableOutputTable.RowName = {};
            app.UIFitParameterTable = uitable(app.ECFitValuesTab);
            app.UIFitParameterTable.ColumnName = {'Parameter'; 'Value'; 'Confidence Interval'; 'Upper Bound'; 'Lower Bound'};
            app.UIFitParameterTable.RowName = {};
            app.UIFitParameterTable.ColumnSortable = [true true false false false];
            app.FittingMethodDropDownLabel = uilabel(app.ECFitValuesTab);
            app.FittingMethodDropDownLabel.Text = 'Fitting Method';
            app.FittingMethodDropDown = uidropdown(app.ECFitValuesTab);
            app.FittingMethodDropDown.Items = {'Initial Guess', 'Simplex', 'Levenberg-Marquardt', 'Trust-Region-Reflective'};
            app.FittingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @FittingMethodDropDownValueChanged, true);
            app.FittingMethodDropDown.Value = 'Initial Guess';
            app.CircuitSelectionTab = uitab(app.TabGroup);
            app.CircuitSelectionTab.Title = 'Circuit Selection';
            app.UIECFittingTable = uitable(app.CircuitSelectionTab);
            app.UIECFittingTable.ColumnName = {'Selection for fit'; 'Equivilient Circuits'; '# of Parameters'; '# of Elements'};
            app.UIECFittingTable.RowName = {};
            app.UIECFittingTable.ColumnSortable = true;
            app.UIECFittingTable.SelectionType = 'row';
            app.UIECFittingTable.ColumnEditable = [true false false false];
            app.CheckButton = uibutton(app.CircuitSelectionTab, 'state');
            app.CheckButton.Text = 'Check';
            app.CircuitValidatorEditFieldLabel = uilabel(app.CircuitSelectionTab);
            app.CircuitValidatorEditFieldLabel.Text = 'Circuit Validator';
            app.CircuitValidatorEditField = uieditfield(app.CircuitSelectionTab, 'text');
            app.SimplifiedCircuitEditFieldLabel = uilabel(app.CircuitSelectionTab);
            app.SimplifiedCircuitEditFieldLabel.Text = 'Simplified Circuit';
            app.SimplifiedCircuitEditField = uieditfield(app.CircuitSelectionTab, 'text');
            app.ResultEditFieldLabel = uilabel(app.CircuitSelectionTab);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.CircuitSelectionTab, 'text');
            app.ResultEditField.Editable = 'off';
            app.AddtolistButton = uibutton(app.CircuitSelectionTab, 'state');
            app.AddtolistButton.ValueChangedFcn = createCallbackFcn(app, @AddtolistButtonValueChanged, true);
            app.AddtolistButton.Text = 'Add to list';
            app.AdvancedSettingsTab = uitab(app.TabGroup);
            app.AdvancedSettingsTab.Title = 'Advanced Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FittingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end